classdef RASTERAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        x0EditFieldLabel           matlab.ui.control.Label
        x0EditField                matlab.ui.control.NumericEditField
        y0EditFieldLabel           matlab.ui.control.Label
        y0EditField                matlab.ui.control.NumericEditField
        y1EditFieldLabel           matlab.ui.control.Label
        y1EditField                matlab.ui.control.NumericEditField
        x1EditFieldLabel           matlab.ui.control.Label
        x1EditField                matlab.ui.control.NumericEditField
        y2EditFieldLabel           matlab.ui.control.Label
        y2EditField                matlab.ui.control.NumericEditField
        x2EditFieldLabel           matlab.ui.control.Label
        x2EditField                matlab.ui.control.NumericEditField
        STATUS                     matlab.ui.control.Button
        ResolutionXEditFieldLabel  matlab.ui.control.Label
        ResolutionXEditField       matlab.ui.control.NumericEditField
        ResolutionYEditFieldLabel  matlab.ui.control.Label
        ResolutionYEditField       matlab.ui.control.NumericEditField
        eg1920Label                matlab.ui.control.Label
        eg1080Label                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(1,1) = app.x0EditField.Value;
        end

        % Value changed function: y0EditField
        function y0EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(1,2) = app.y0EditField.Value;
        end

        % Value changed function: x1EditField
        function x1EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(2,1) = app.x1EditField.Value;
        end

        % Value changed function: y1EditField
        function y1EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(2,2) = app.y1EditField.Value;
        end

        % Value changed function: x2EditField
        function x2EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(3,1) = app.x2EditField.Value;
        end

        % Value changed function: y2EditField
        function y2EditFieldValueChanged(app, event)
            global A ; %A is 3 by 2 matix
            A(3,2) = app.y2EditField.Value;
        end

        % Button pushed function: STATUS
        function STATUSPushed(app, event)
            global A ; %A is 3 by 2 matix
            global resolutionX;
            global resolutionY;
            A(isnan(A)) = 0;
            if isnan(resolutionY)
                resolutionY = 0;
            end
            if isnan(resolutionX)
                resolutionX = 0;
            end
            if sum(A(:,2)>resolutionY)
                app.STATUS.Text = 'Y coordinates can not be bigger than the resolution';
            elseif sum(A(:,1)>resolutionX)
                app.STATUS.Text = 'X coordinates can not be bigger than the resolution';
            else
                app.STATUS.Text = 'Rasterizing';
                app.STATUS.FontColor = [0.9608 0.2804 0.1608];
                framebuffer = uint8(zeros(resolutionY,resolutionX));
                framebuffer = DrawTriangle(A, framebuffer);
                app.Image.ImageSource = repmat(framebuffer, 1, 1, 3);
                drawnow;
                app.STATUS.Text = 'RASTERIZE';
                app.STATUS.FontColor = [0.1608 0.5804 0.1608];
                drawnow;
            end


        end

        % Value changed function: ResolutionXEditField
        function ResolutionXEditFieldValueChanged(app, event)
            global resolutionX;
            resolutionX = app.ResolutionXEditField.Value;
        end

        % Value changed function: ResolutionYEditField
        function ResolutionYEditFieldValueChanged(app, event)
            global resolutionY;
            resolutionY = app.ResolutionYEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.y0EditField.ValueChangedFcn = createCallbackFcn(app, @y0EditFieldValueChanged, true);
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.y1EditField.ValueChangedFcn = createCallbackFcn(app, @y1EditFieldValueChanged, true);
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @x1EditFieldValueChanged, true);
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.y2EditField.ValueChangedFcn = createCallbackFcn(app, @y2EditFieldValueChanged, true);
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField.ValueChangedFcn = createCallbackFcn(app, @x2EditFieldValueChanged, true);
            app.STATUS = uibutton(app.UIFigure, 'push');
            app.STATUS.ButtonPushedFcn = createCallbackFcn(app, @STATUSPushed, true);
            app.STATUS.Text = 'RASTERIZE';
            app.ResolutionXEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionXEditFieldLabel.Text = 'ResolutionX';
            app.ResolutionXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionXEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionXEditFieldValueChanged, true);
            app.ResolutionYEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionYEditFieldLabel.Text = 'ResolutionY';
            app.ResolutionYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionYEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionYEditFieldValueChanged, true);
            app.eg1920Label = uilabel(app.UIFigure);
            app.eg1920Label.Text = 'eg.1920';
            app.eg1080Label = uilabel(app.UIFigure);
            app.eg1080Label.Text = 'eg.1080';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RASTERAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end